classdef SpinopelvicPara < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AutomaticSpinopelvicParametersMenu  matlab.ui.container.Menu
        Menu                         matlab.ui.container.Menu
        NewPatientButton             matlab.ui.control.Button
        PILLstandingLabel            matlab.ui.control.Label
        SacralSlopeLabel             matlab.ui.control.Label
        LLLabel_2                    matlab.ui.control.Label
        PILabel_2                    matlab.ui.control.Label
        PTLabel_2                    matlab.ui.control.Label
        SSLabel_2                    matlab.ui.control.Label
        LLLabel                      matlab.ui.control.Label
        PILabel                      matlab.ui.control.Label
        PTLabel                      matlab.ui.control.Label
        SSLabel                      matlab.ui.control.Label
        StffnessAlignmentKnob        matlab.ui.control.DiscreteKnob
        StffnessAlignmentKnobLabel   matlab.ui.control.Label
        UITable_stand                matlab.ui.control.Table
        StandSpinopelvicParametersButton  matlab.ui.control.Button
        LoadStandingXrayButton       matlab.ui.control.Button
        UITable_stiffness            matlab.ui.control.Table
        StiffnessParametersButton    matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        SpinopelvicParametersButton  matlab.ui.control.Button
        LoadSittingXrayButton        matlab.ui.control.Button
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %% Global parmeters 
        fileLoc = '';
        pathloc = '';
        sizeloc = '';
        imageloc = '';
        imgnameloc = '';
        maxszloc = '';
        paraloc = '';
        fileLoc2 = '';
        pathloc2 = '';
        sizeloc2 = '';
        imageloc2 = '';
        imgnameloc2 = '';
        maxszloc2 = '';
        paraloc2 = '';
        StiffVarloc = '';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadSittingXrayButton
        function LoadSittingXrayButtonPushed(app, event)
            %% import sitting X-ray image
            ext= '*.*';
            folder='./IntellijointData';
            [filename, path] = uigetfile(fullfile(folder,ext)) ;
            f = imread(fullfile(path, filename));               
            [imfilepath,imname,imext] = fileparts(filename);               
            sz = size(f);
            maxsz = max(sz);
            
            %% image display and setups
            imshow(f,'Parent',app.UIAxes);                
            app.UIAxes.Toolbar.Visible = 'on';
            zoom(app.UIFigure,"on")
            hold(app.UIAxes,"on")
            
            %% kepping local parameters              
            app.fileLoc = filename;
            app.pathloc = path;
            app.imgnameloc = imname;
            app.sizeloc = sz;
            app.imageloc = f;
            app.maxszloc = maxsz;        
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Callback function
        function LoadStandingPostureXrayButtonPushed(app, event)
         
        end

        % Button pushed function: SpinopelvicParametersButton
        function SpinopelvicParametersButtonPushed(app, event)
            %% importing the trained model and run the model in python
            system ('pwd')
            
            %% install pip through matlab (only execute for first run)

            % !python3 -m pip install
            % !/Library/Developer/CommandLineTools/usr/bin/python3 -m pip install --upgrade pip
            %% cd to directory with requirements.txt
            % !pip3 install -r requirements.txt
            % run
                     
            PyCommand = 'python3';  % Python3 is necessary to be installed          
            ModelPath = ' ./yolov5/detect.py';
            source = ' --source ';
            file_name = app.fileLoc;
            path = app.pathloc;
            ImgPath = [path,file_name];            
            weights=  ' --weights';
            WeightsPath = ' ./Intellijoint_Final_NewCode/train_weights/weights/best.pt';
            ModelOption = ' --save-conf  --save-txt --name results --exist-ok';
            command = [PyCommand,ModelPath,source,ImgPath,weights,WeightsPath,ModelOption];
            system (command);
            %% Post processing of predicted landmarks 
            SaveDirectory = './yolov5/runs/detect/results/labels';            
            Prediction = [app.imgnameloc,'.txt']; 
            Preddata = load(fullfile(SaveDirectory,Prediction));  
            szval = size(Preddata);
            
            %%%% extracting the best prediction with highest confidence %%%
            conf(:,:) = zeros(szval(1,1),1);
            for m = 1:8
                for j = 1:szval(1,1)
                    if Preddata(j,1) == m-1
                        BestConf(m,j) = Preddata(j,6);
                       if BestConf(m,j) > conf(j,1)
                           conf(j,1) = BestConf(m,j);
                           A(m,:) = [m-1,j];
                       end
                    end
                end             
            end
        
            for m = 1:8
                if A(m,2) ~= 0  
                   landmarks(m,:) = Preddata(A(m,2),2:3);
                   BoundBox (m,:) = Preddata(A(m,2),5:6);
                end
            end       
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            loadlandmrk = landmarks;
            loadlandmrk = [loadlandmrk(:,1) .* app.sizeloc(1,2),loadlandmrk(:,2) .* app.sizeloc(1,1)];
            loadBoundBox = [BoundBox(:,1) .* app.sizeloc(1,2),BoundBox(:,2) .* app.sizeloc(1,1)];
            r_hip_post = loadBoundBox(1,1) / 2; 
            r_hip_ant = loadBoundBox(2,1) / 2;
            hold(app.UIAxes,"on")
            app.UIAxes.Toolbar.Visible = 'on';
            zoom(app.UIAxes,"on")
            %%%%%%----- Vidualization of Predicted landmarks-----%%%%%%%%%%
            pointsize = 2;
            post_S1_top(:,:) = loadlandmrk(1,:);
            appviscircles(app.UIAxes,post_S1_top(:,:),pointsize,'color','green');
            ant_S1_top(:,:)  = loadlandmrk(2,:);
            appviscircles(app.UIAxes,ant_S1_top(:,:),pointsize,'color','green');
            post_L5_bot(:,:) = loadlandmrk(3,:);
            appviscircles(app.UIAxes,post_L5_bot(:,:),pointsize,'color','green');
            ant_L5_bot(:,:)  = loadlandmrk(4,:);
            appviscircles(app.UIAxes,ant_L5_bot(:,:),pointsize,'color','green');
            post_L1_top(:,:) = loadlandmrk(5,:);
            appviscircles(app.UIAxes,post_L1_top(:,:),pointsize,'color','green');
            ant_L1_top(:,:)  = loadlandmrk(6,:);
            appviscircles(app.UIAxes,ant_L1_top(:,:),pointsize,'color','green');            
            post_FH(:,:) = loadlandmrk(7,:);
            if post_FH(:,:) ~= 0
               appviscircles(app.UIAxes,post_FH(:,:),pointsize,'color','green');
            end
    
            ant_FH(:,:)  = loadlandmrk(8,:);
            if post_FH(:,:) ~= 0
               appviscircles(app.UIAxes,ant_FH(:,:),pointsize,'color','green');
            end
            
            
            %%%%%%%%%%%%%%--------- Sacrum Slope (SS)------------%%%%%%%%%%
            line(app.UIAxes,[post_S1_top(1,1),ant_S1_top(1,1)], ...
                [post_S1_top(1,2),ant_S1_top(1,2)],'Color','w','LineStyle','-','LineWidth',1.5)
            midS1 = (post_S1_top(:,:) + ant_S1_top(:,:))/2;
            appviscircles(app.UIAxes,midS1,pointsize/3,'color','k');
            
            if  post_S1_top(1,1) < ant_S1_top(1,1) % Left or right X-ray face
                theta_ss = atan2((post_S1_top(1,2) - ant_S1_top(1,2)),(post_S1_top(1,1) - ant_S1_top(1,1)));
                Ref_HZn = line(app.UIAxes,[ant_S1_top(1,1),ant_S1_top(1,1) - app.maxszloc/15],...
                    [ant_S1_top(1,2),ant_S1_top(1,2)],'Color','w','LineStyle','--','LineWidth',0.8);
                theta_Hzn = atan2((post_S1_top(1,2) - post_S1_top(1,2)), ...
                    (post_S1_top(1,1) + app.maxszloc/10-post_S1_top(1,1)));    
            else        
                theta_ss = atan2((ant_S1_top(1,2) - post_S1_top(1,2)),(ant_S1_top(1,1) - post_S1_top(1,1)));
                Ref_HZn = line(app.UIAxes,[ant_S1_top(1,1),ant_S1_top(1,1) + app.maxszloc/15], ...
                    [ant_S1_top(1,2),ant_S1_top(1,2)],'Color','w','LineStyle','--','LineWidth',0.8);
                theta_Hzn = atan2((ant_S1_top(1,2) - ant_S1_top(1,2)),(ant_S1_top(1,1) + app.maxszloc/10-ant_S1_top(1,1)));    
            end
    
            SS = (theta_ss) * 180/pi;
            if SS > 90
                SS_2 = 180 - SS;
            end
          
            %%%%%%%%%%%%%%-------- Lumbar Lordosis------------%%%%%%%%%%%%%
            midL5 = (post_L5_bot(:,:) + ant_L5_bot(:,:)) / 2;
            appviscircles(app.UIAxes,midL5,pointsize / 2,'color','k');
            midL1 = (post_L1_top(:,:)+ant_L1_top(:,:)) / 2;
            appviscircles(app.UIAxes,midL1,pointsize/2,'color','k');
            
            v_1 = [ant_L5_bot(1,1),ant_L5_bot(1,2),0] - [post_L5_bot(1,1),post_L5_bot(1,2),0];
            v_2 = [ant_L1_top(1,1),ant_L1_top(1,2),0] - [post_L1_top(1,1),post_L1_top(1,2),0];
            Theta = atan2(norm(cross(v_1, v_2)), dot(v_1, v_2));
            LL = 180 * Theta / pi; 
         
            m1 = (ant_L5_bot(1,2) - post_L5_bot(1,2)) / (ant_L5_bot(1,1) - post_L5_bot(1,1));
            b1 = ant_L5_bot(1,2) - m1*ant_L5_bot(1,1);
            m2 = (ant_L1_top(1,2) - post_L1_top(1,2)) / (ant_L1_top(1,1) - post_L1_top(1,1));
            b2 = ant_L1_top(1,2) - m2*ant_L1_top(1,1);
    
            xf1 = post_L5_bot(1,1) + app.maxszloc/10;
            yf1 = m1 * xf1 + b1;
    
            xf2 = post_L5_bot(1,1) + app.maxszloc/10;
            yf2 = m2 * xf2 + b2;
    
            x_int = (b2 - b1) / (m1 - m2);
            y_int = m1 * x_int + b1;
            
            p_int = [x_int,y_int];
            
            line(app.UIAxes,[xf1, post_L5_bot(1,1)],...
                [yf1, post_L5_bot(1,2)],'Color','w','LineStyle','--')
            line(app.UIAxes,[xf2, post_L1_top(1,1)],...
                [yf2, post_L1_top(1,2)],'Color','w','LineStyle','--')
    
            %%%%%%%%%%%%% Femoral Head and PI PT calculation %%%%%%%%%%%%%%
            [xout,yout] = circcirc(post_FH(1,1),post_FH(1,2), r_hip_post,ant_FH(1,1),ant_FH(1,2),r_hip_ant);
            line(app.UIAxes,[xout(1,1),xout(1,2)],[yout(1,1),yout(1,2)],'Color','cyan','LineStyle','-');
            
            RotCent(1,1) = (xout(1,1) + xout(1,2)) / 2;
            RotCent(1,2) = (yout(1,1) + yout(1,2)) / 2;
            
            if isnan(RotCent)
               RotCent = [(post_FH(1,1) + ant_FH(1,1)) / 2,(post_FH(1,2) + ant_FH(1,2))/2];
            end
            
            if isnan(RotCent)
               RotCent = [post_FH(1,1),post_FH(1,2)];
            end
            
            if isnan(RotCent)
               RotCent = [ant_FH(1,1),ant_FH(1,2)];
            end
    
            if landmarks(8,:) == 0 
               RotCent = [post_FH(1,1),post_FH(1,2)];
            end
            
            if landmarks(7,:) == 0 
               RotCent = [ant_FH(1,1),ant_FH(1,2)];
            end
            
            Ref_Ver = line(app.UIAxes,[RotCent(1,1),RotCent(1,1)],[RotCent(1,2),RotCent(1,2) - app.maxszloc/10], ...
                'Color','w','LineStyle','--');
            Theta_ver = atan2(((RotCent(1,2) - app.maxszloc/5) - RotCent(1,2)),(RotCent(1,1) - RotCent(1,1)));
                                
            line(app.UIAxes,[midS1(1,1),RotCent(1,1)],[midS1(1,2),RotCent(1,2)],'Color','w','LineStyle','-');
            
            l = sqrt((ant_S1_top(1,1) - post_S1_top(1,1)) ^ 2 + (ant_S1_top(1,2) - post_S1_top(1,2)) ^ 2);
            if post_S1_top(1,1) < ant_S1_top(1,1)                  
               P3PI = [post_S1_top(1,1) - l/2/cosd(SS),post_S1_top(1,2)];
               else
               P3PI = [ant_S1_top(1,1) - l/2/cosd(SS),ant_S1_top(1,2)];
            end
            
            m3 = (midS1(1,2) - P3PI(1,2)) / (midS1(1,1) - P3PI(1,1));
            b3 = P3PI(1,2) - m3 * P3PI(1,1);
    
            xf3 = midS1(1,1) + app.maxszloc / 100;
            yf3 = m3 * xf3 + b3;
            line(app.UIAxes,[xf3,midS1(1,1)],[yf3,midS1(1,2)],'Color','w','LineStyle','--');
            
            %%%%%%%%%%---------------- PT & PI ------------------%%%%%%%%%
            if SS>90
                    SS = 180 - SS;
            end
            if SS<0
                    SS = 180 + SS;
            end
            
            Theta_PT = atan2((RotCent(1,2) - midS1(1,2)),(RotCent(1,1) - midS1(1,1))) - abs(Theta_ver);
            PT = (Theta_PT) * 180 / pi;
            PI = abs(PT) + abs(SS);
            PT = abs(PT);
            format bank 
            para = [SS,PT,PI,LL];        
            app.paraloc = para;
            app.UITable.Data = app.paraloc;     
            hold(app.UIAxes,"off")
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.UITable.Data = app.paraloc;
        end

        % Button pushed function: LoadStandingXrayButton
        function LoadStandingXrayButtonPushed(app, event)
            %% import standing X-ray image
            ext = '*.*' ; 
            folder = './IntellijointData';
            [filename2, path2] = uigetfile(fullfile(folder,ext)) ;
            f2 = imread(fullfile(path2, filename2));
            [imfilepath,imname2,imext] = fileparts(filename2);               
            sz2 = size(f2);
            maxsz2 = max(sz2);
            imshow(f2,'Parent',app.UIAxes_2);
            
            %% local parameters
            app.fileLoc2 = filename2; 
            app.pathloc2 = path2;
            app.imgnameloc2 = imname2;
            app.sizeloc2 = sz2;
            app.imageloc2 = f2;
            app.maxszloc2 = maxsz2;
                
        end

        % Button pushed function: StandSpinopelvicParametersButton
        function StandSpinopelvicParametersButtonPushed(app, event)
            %% importing the trained model and run the model in python
            PyCommand = 'python3';            
            ModelPath = ' ./yolov5/detect.py';
            source = ' --source ';
            filename2 = app.fileLoc2;
            path2 = app.pathloc2;
            ImgPath2 = [path2,filename2];            
            weights=  ' --weights';
            WeightsPath = ' ./Intellijoint_Final_NewCode/train_weights/weights/best.pt';
            ModelOption = ' --save-conf  --save-txt --name results --exist-ok';
            command = [PyCommand,ModelPath,source,ImgPath2,weights,WeightsPath,ModelOption];
            system (command);
            %% Post processing of predicted landmarks
            SaveDirectory = './yolov5/runs/detect/results/labels';            
            Prediction = [app.imgnameloc2,'.txt']; 
            Preddata = load(fullfile(SaveDirectory,Prediction));         
            szval = size(Preddata);
            conf(:,:) = zeros(szval(1,1),1);

            for m = 1:8
                for j = 1:szval(1,1)
                    if Preddata(j,1) == m - 1
                        BestConf(m,j) = Preddata(j,6);
                       if BestConf(m,j) > conf(j,1)
                           conf(j,1) = BestConf(m,j);
                           A(m,:) = [m-1,j];
                       end
                    end
                end             
            end
        
            for m = 1:8
                if A(m,2) ~= 0  
                   landmarks(m,:)= Preddata(A(m,2),2:3);
                   BoundBox (m,:) = Preddata(A(m,2),5:6);
                end
            end            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            loadlandmrk = landmarks;
            loadlandmrk = [loadlandmrk(:,1) .* app.sizeloc(1,2),loadlandmrk(:,2) .* app.sizeloc(1,1)];
            loadBoundBox = [BoundBox(:,1) .* app.sizeloc(1,2),BoundBox(:,2) .* app.sizeloc(1,1)];
            r_hip_post = loadBoundBox(1,1) / 2;
            r_hip_ant = loadBoundBox(2,1) / 2;        
            hold(app.UIAxes_2,"on")
            %%%%%%----- Vidualization of Predicted landmarks-----%%%%%%%%%%
            pointsize = 2;
            post_S1_top(:,:) = loadlandmrk(1,:);
            appviscircles(app.UIAxes_2,post_S1_top(:,:),pointsize,'color','green');
            ant_S1_top(:,:)  = loadlandmrk(2,:);
            appviscircles(app.UIAxes_2,ant_S1_top(:,:),pointsize,'color','green');
            post_L5_bot(:,:) = loadlandmrk(3,:);
            appviscircles(app.UIAxes_2,post_L5_bot(:,:),pointsize,'color','green');
            ant_L5_bot(:,:)  = loadlandmrk(4,:);
            appviscircles(app.UIAxes_2,ant_L5_bot(:,:),pointsize,'color','green');
            post_L1_top(:,:) = loadlandmrk(5,:);
            appviscircles(app.UIAxes_2,post_L1_top(:,:),pointsize,'color','green');
            ant_L1_top(:,:)  = loadlandmrk(6,:);
            appviscircles(app.UIAxes_2,ant_L1_top(:,:),pointsize,'color','green');
            post_FH(:,:) = loadlandmrk(7,:);
            if post_FH(:,:) ~= 0
               appviscircles(app.UIAxes_2,post_FH(:,:),pointsize,'color','green');
            end
    
            ant_FH(:,:)  = loadlandmrk(8,:);
            if post_FH(:,:) ~= 0
               appviscircles(app.UIAxes_2,ant_FH(:,:),pointsize,'color','green');
            end
            
            %%%%%%%%%%%--------- Sacrum Slope (SS)------------%%%%%%%%%%%%
            line(app.UIAxes_2,[post_S1_top(1,1),ant_S1_top(1,1)],[post_S1_top(1,2),ant_S1_top(1,2)], ...
                'Color','w','LineStyle','-','LineWidth',1.5)
            midS1 = (post_S1_top(:,:) + ant_S1_top(:,:)) / 2;
            appviscircles(app.UIAxes_2,midS1,pointsize/3,'color','k');
            
            if  post_S1_top(1,2) < ant_S1_top(1,2)   
                theta_ss = atan2((ant_S1_top(1,2) - post_S1_top(1,2)),(ant_S1_top(1,1) - post_S1_top(1,1)));
                Ref_HZn = line(app.UIAxes_2,[ant_S1_top(1,1),ant_S1_top(1,1) + app.maxszloc2/15],...
                    [ant_S1_top(1,2),ant_S1_top(1,2)],'Color','w','LineStyle','--','LineWidth',0.8);
                theta_Hzn = atan2((post_S1_top(1,2) - post_S1_top(1,2)),(post_S1_top(1,1) + app.maxszloc2/10-post_S1_top(1,1))); 
            else
                theta_ss = atan2((post_S1_top(1,2) - ant_S1_top(1,2)),(post_S1_top(1,1) - ant_S1_top(1,1)));
                Ref_HZn = line(app.UIAxes_2,[midS1(1,1),midS1(1,1) - app.maxszloc2/15],...
                    [midS1(1,2),midS1(1,2)],'Color','k','LineStyle','-');
                theta_Hzn = atan2((ant_S1_top(1,2) - ant_S1_top(1,2)),(ant_S1_top(1,1) + app.maxszloc2/10 - ant_S1_top(1,1)));   
            end
            SS = (theta_ss) * 180 / pi;
            if SS > 90
                    SS_2 = 180 - SS;
            end
          
            %%%%-------- Lumbar Lordosis------------%%%%%%%%%%%%
            midL5 = (post_L5_bot(:,:) + ant_L5_bot(:,:)) / 2;
            appviscircles(app.UIAxes_2,midL5,pointsize / 2,'color','k');
            midL1 = (post_L1_top(:,:) + ant_L1_top(:,:)) / 2;
            appviscircles(app.UIAxes_2,midL1,pointsize / 2,'color','k');
            
            v_1 = [ant_L5_bot(1,1),ant_L5_bot(1,2),0] - [post_L5_bot(1,1),post_L5_bot(1,2),0];
            v_2 = [ant_L1_top(1,1),ant_L1_top(1,2),0] - [post_L1_top(1,1),post_L1_top(1,2),0];
            Theta = atan2(norm(cross(v_1, v_2)), dot(v_1, v_2));
            LL = 180 * Theta / pi; 
    
            m1 = (ant_L5_bot(1,2) - post_L5_bot(1,2)) / (ant_L5_bot(1,1) - post_L5_bot(1,1));
            b1 = ant_L5_bot(1,2) - m1*ant_L5_bot(1,1);
            m2 = (ant_L1_top(1,2) - post_L1_top(1,2)) / (ant_L1_top(1,1) - post_L1_top(1,1));
            b2 = ant_L1_top(1,2) - m2 * ant_L1_top(1,1);
    
            xf1 = post_L5_bot(1,1) + app.maxszloc2/10;
            yf1 = m1 * xf1 + b1;
    
            xf2 = post_L5_bot(1,1) + app.maxszloc2/10;
            yf2 = m2 * xf2 + b2;
    
            x_int = (b2-b1) / (m1-m2);
            y_int = m1 * x_int + b1;
            
            p_int = [x_int,y_int];
            
            line(app.UIAxes_2,[xf1, post_L5_bot(1,1)],[yf1, post_L5_bot(1,2)],'Color','w','LineStyle','--')
            line(app.UIAxes_2,[xf2, post_L1_top(1,1)],[yf2, post_L1_top(1,2)],'Color','w','LineStyle','--')
    
            %%%%%%%%%%%%% Femoral Head and PI PT calculation %%%%%%%%%%%%%%
    
            [xout,yout] = circcirc(post_FH(1,1),post_FH(1,2),r_hip_post,ant_FH(1,1),ant_FH(1,2),r_hip_ant);
            line(app.UIAxes_2,[xout(1,1),xout(1,2)],[yout(1,1),yout(1,2)],'Color','cyan','LineStyle','-');
            
            RotCent(1,1) = (xout(1,1) + xout(1,2)) / 2;
            RotCent(1,2) = (yout(1,1) + yout(1,2)) / 2;
            
            if isnan(RotCent)
               RotCent = [(post_FH(1,1) + ant_FH(1,1))/2,(post_FH(1,2) + ant_FH(1,2))/2];
            end
            
            if isnan(RotCent)
               RotCent = [post_FH(1,1),post_FH(1,2)];
            end
            
            if isnan(RotCent)
               RotCent = [ant_FH(1,1),ant_FH(1,2)];
            end
    
            if landmarks(8,:) == 0 
               RotCent = [post_FH(1,1),post_FH(1,2)];
            end
            
            if landmarks(7,:) == 0 
               RotCent = [ant_FH(1,1),ant_FH(1,2)];
            end
            
            Ref_Ver = line(app.UIAxes_2,[RotCent(1,1),RotCent(1,1)],[RotCent(1,2),RotCent(1,2) - app.maxszloc2/10],...
                'Color','w','LineStyle','--');
            Theta_ver = atan2(((RotCent(1,2) - app.maxszloc2/5) - RotCent(1,2)),(RotCent(1,1) - RotCent(1,1)));
                                
            line(app.UIAxes_2,[midS1(1,1),RotCent(1,1)],[midS1(1,2),RotCent(1,2)],'Color','w','LineStyle','-');
            
            l = sqrt((ant_S1_top(1,1) - post_S1_top(1,1)) ^ 2 + (ant_S1_top(1,2) - post_S1_top(1,2)) ^ 2);
            if post_S1_top(1,1) < ant_S1_top(1,1)                  
               P3PI = [post_S1_top(1,1) - l/2/cosd(SS),post_S1_top(1,2)];
               else
               P3PI = [ant_S1_top(1,1) - l/2/cosd(SS),ant_S1_top(1,2)];
            end
            
            m3 = (midS1(1,2) - P3PI(1,2)) / (midS1(1,1) - P3PI(1,1));
            b3 = P3PI(1,2) - m3 * P3PI(1,1);
    
            xf3 = midS1(1,1) + app.maxszloc2 / 100;
            yf3 = m3 * xf3 + b3;
            line(app.UIAxes_2,[xf3,midS1(1,1)],[yf3,midS1(1,2)],...
                'Color','w','LineStyle','--');
            
            %%%%%%%%%%%%%%----------- PT & PI ------------------%%%%%%%%%%%
            if SS > 90
                    SS = 180 - SS;
            end
            
            Theta_PT = atan2((RotCent(1,2) - midS1(1,2)),(RotCent(1,1) - midS1(1,1))) - abs(Theta_ver);
            PT = (Theta_PT) * 180 / pi;
            PI = abs(PT) + abs(SS);
            PT = abs(PT);
            para = [SS,PT,PI,LL];        
            app.paraloc2 = para;
            app.UITable_stand.Data = app.paraloc2;
            hold(app.UIAxes_2,"off")

        end

        % Button pushed function: StiffnessParametersButton
        function StiffnessParametersButtonPushed(app, event)
             
            StiffVar = [abs(app.paraloc2(1,1) - app.paraloc(1,1)),(app.paraloc2(1,3) - app.paraloc2(1,4))];
             app.UITable_stiffness.Data = StiffVar;
             app.StiffVarloc = StiffVar;
             knobItem = app.StffnessAlignmentKnob.Items;

            if app.StiffVarloc(1,1) < 10 && app.StiffVarloc(1,2) > 10
                
                set(app.StffnessAlignmentKnob,'Value','Stiff & Deformity');
            
            end

            if app.StiffVarloc(1,1) > 10 && app.StiffVarloc(1,2) < 10
                set(app.StffnessAlignmentKnob,'Value','Normal');
            end

            if app.StiffVarloc(1,1) < 10 && app.StiffVarloc(1,2) < 10
                set(app.StffnessAlignmentKnob,'Value','Stiff');
            end

            if app.StiffVarloc(1,1) > 10 && app.StiffVarloc(1,2) > 10
                set(app.StffnessAlignmentKnob,'Value','Deformity');
            end
                 
        end

        % Value changed function: StffnessAlignmentKnob
        function StffnessAlignmentKnobValueChanged(app, event)

        end

        % Button pushed function: NewPatientButton
        function NewPatientButtonPushed(app, event)
            %% clear all data and workspace
            set(app.StffnessAlignmentKnob,'Value','NA');
            imshow([],[],'Parent',app.UIAxes);
            imshow([],[],'Parent',app.UIAxes_2);            
            app.UITable_stand.Data     = [];
            app.UITable.Data           = [];
            app.UITable_stiffness.Data = [];
            clc;
            clear all;
        end

        % Menu selected function: Menu
        function MenuSelected(app, event)
            app.UIFigure = uifigure('Visible', 'off');
            fig = uifigure;
            m = uimenu(fig);
%             m.Text = 'Open Selection';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AutomaticSpinopelvicParametersMenu = uimenu(app.UIFigure);
            app.AutomaticSpinopelvicParametersMenu.Text = 'Automatic Spinopelvic Parameters';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.Menu.Visible = 'off';
            app.Menu.Enable = 'off';
            app.Menu.Text = 'Menu';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sitting X-ray')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Standing X-ray')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.LoadSittingXrayButton = uibutton(app.UIFigure, 'push');
            app.LoadSittingXrayButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSittingXrayButtonPushed, true);
            app.LoadSittingXrayButton.Text = 'Load Sitting X-ray';
            app.SpinopelvicParametersButton = uibutton(app.UIFigure, 'push');
            app.SpinopelvicParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SpinopelvicParametersButtonPushed, true);
            app.SpinopelvicParametersButton.Text = 'Spinopelvic Parameters';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {''; ''; ''; ''};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.StiffnessParametersButton = uibutton(app.UIFigure, 'push');
            app.StiffnessParametersButton.ButtonPushedFcn = createCallbackFcn(app, @StiffnessParametersButtonPushed, true);
            app.StiffnessParametersButton.Text = 'Stiffness Parameters';
            app.UITable_stiffness = uitable(app.UIFigure);
            app.UITable_stiffness.ColumnName = {''; ''};
            app.UITable_stiffness.RowName = {};
            app.LoadStandingXrayButton = uibutton(app.UIFigure, 'push');
            app.LoadStandingXrayButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStandingXrayButtonPushed, true);
            app.LoadStandingXrayButton.Text = 'Load Standing X-ray';
            app.StandSpinopelvicParametersButton = uibutton(app.UIFigure, 'push');
            app.StandSpinopelvicParametersButton.ButtonPushedFcn = createCallbackFcn(app, @StandSpinopelvicParametersButtonPushed, true);
            app.StandSpinopelvicParametersButton.Text = 'Spinopelvic Parameters';
            app.UITable_stand = uitable(app.UIFigure);
            app.UITable_stand.ColumnName = {''; ''; ''; ''};
            app.UITable_stand.RowName = {};
            app.StffnessAlignmentKnobLabel = uilabel(app.UIFigure);
            app.StffnessAlignmentKnobLabel.Text = 'Stffness | Alignment';
            app.StffnessAlignmentKnob = uiknob(app.UIFigure, 'discrete');
            app.StffnessAlignmentKnob.Items = {'NA', 'Normal', 'Stiff & Deformity', 'Stiff', 'Deformity'};
            app.StffnessAlignmentKnob.ValueChangedFcn = createCallbackFcn(app, @StffnessAlignmentKnobValueChanged, true);
            app.StffnessAlignmentKnob.Value = 'NA';
            app.SSLabel = uilabel(app.UIFigure);
            app.SSLabel.Text = 'SS°';
            app.PTLabel = uilabel(app.UIFigure);
            app.PTLabel.Text = 'PT°';
            app.PILabel = uilabel(app.UIFigure);
            app.PILabel.Text = 'PI°';
            app.LLLabel = uilabel(app.UIFigure);
            app.LLLabel.Text = 'LL°';
            app.SSLabel_2 = uilabel(app.UIFigure);
            app.SSLabel_2.Text = 'SS°';
            app.PTLabel_2 = uilabel(app.UIFigure);
            app.PTLabel_2.Text = 'PT°';
            app.PILabel_2 = uilabel(app.UIFigure);
            app.PILabel_2.Text = 'PI°';
            app.LLLabel_2 = uilabel(app.UIFigure);
            app.LLLabel_2.Text = 'LL°';
            app.SacralSlopeLabel = uilabel(app.UIFigure);
            app.SacralSlopeLabel.Text = '𝚫 Sacral Slope °';
            app.PILLstandingLabel = uilabel(app.UIFigure);
            app.PILLstandingLabel.Text = '(PI - LL)°  standing';
            app.NewPatientButton = uibutton(app.UIFigure, 'push');
            app.NewPatientButton.ButtonPushedFcn = createCallbackFcn(app, @NewPatientButtonPushed, true);
            app.NewPatientButton.Text = 'New Patient';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpinopelvicPara

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end